classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LEDGridButton             matlab.ui.control.Button
        TriggerEnableSwitch       matlab.ui.control.Switch
        TriggerEnableSwitchLabel  matlab.ui.control.Label
        offsetYSpinner            matlab.ui.control.Spinner
        offsetYSpinnerLabel       matlab.ui.control.Label
        offsetXSpinner            matlab.ui.control.Spinner
        offsetXSpinnerLabel       matlab.ui.control.Label
        LineButton                matlab.ui.control.StateButton
        ProbeGridButton           matlab.ui.control.Button
        YSpinner                  matlab.ui.control.Spinner
        YSpinnerLabel             matlab.ui.control.Label
        XSpinner                  matlab.ui.control.Spinner
        XSpinnerLabel             matlab.ui.control.Label
        Label2                    matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        RDButton                  matlab.ui.control.StateButton
        LUButton                  matlab.ui.control.StateButton
        SavescreenButton          matlab.ui.control.Button
        MarksCheckBox             matlab.ui.control.CheckBox
        PointsButton              matlab.ui.control.StateButton
        ExposureEditField         matlab.ui.control.NumericEditField
        ExposureEditFieldLabel    matlab.ui.control.Label
        SnapshotButton            matlab.ui.control.Button
        BackgroundCheckBox        matlab.ui.control.CheckBox
        GrabButton                matlab.ui.control.Button
        PreviewButton             matlab.ui.control.Button
        OpenButton                matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        vid % Description
        hImage
        basepic
        allpic
        draw_points = [];
        lu_point
        rd_point
    end
    
    methods (Access = private)
        
        function mypreview_fcn(app,obj,event,himage)
            if app.BackgroundCheckBox.Value
                a = double(event.Data) + double(app.basepic);
                amaxmin = max(a(:))-min(a(:));
                amin = min(a(:));
                b=(a-amin)./(amaxmin).*255;
                pause(0.1);
                app.allpic = uint8(b);
                himage.CData = app.allpic;
            else
                app.allpic = event.Data;
                himage.CData = app.allpic;
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.vid = videoinput("gentl", 1, "Mono8");
            %app.vid = videoinput("winvideo", 1, "MJPG_1280x720");
            %app.vid.ReturnedColorspace = "grayscale";
            
            app.OpenButton.Enable = "off";
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            vidRes = app.vid.VideoResolution;
            imWidth = vidRes(1);
            imHeight = vidRes(2);
            nBands = app.vid.NumberOfBands;
            app.basepic = zeros(imHeight, imWidth, nBands);
            app.hImage = image(app.UIAxes, app.basepic);
            setappdata(app.hImage, 'UpdatePreviewWindowFcn', @(obj,event,himage) mypreview_fcn(app,obj,event,himage));
            app.UIAxes
            
            preview(app.vid, app.hImage);
        end

        % Button pushed function: GrabButton
        function GrabButtonPushed(app, event)
            app.basepic = getsnapshot(app.vid);
        end

        % Button pushed function: SnapshotButton
        function SnapshotButtonPushed(app, event)
            pic = getsnapshot(app.vid);
            pic = imadjust(pic);
            imwrite(pic, "camerapic\" + string(datetime(datetime,'Format','yy-MM-dd=hh-mm-ss')) + '.png')
        end

        % Value changed function: ExposureEditField
        function ExposureEditFieldValueChanged(app, event)
            value = app.ExposureEditField.Value;
            src = getselectedsource(app.vid);
            src.ExposureTime = value;
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            loc = app.UIAxes.CurrentPoint(1,1:2)
            if app.PointsButton.Value
                
                ppoints = [app.draw_points loc']
                app.draw_points = ppoints;
                app.PointsButton.Value = false;

                value = app.MarksCheckBox.Value;
            
                if value
                    hold(app.UIAxes, "on")
                    plot(app.UIAxes, app.draw_points(1,:), app.draw_points(2,:), 'ro');
                    hold(app.UIAxes, "off")
                end
            end

            if app.LUButton.Value
                
                app.lu_point = loc';
                app.LUButton.Value = false;

                app.Label.Text = sprintf("(%4d,%4d)",round(app.lu_point(1)),round(app.lu_point(2)));

                hold(app.UIAxes, "on")
                plot(app.UIAxes, app.lu_point(1,:), app.lu_point(2,:), 'go');
                hold(app.UIAxes, "off")

            end

            if app.RDButton.Value
                
                app.rd_point = loc';
                app.RDButton.Value = false;

                app.Label2.Text = sprintf("(%4d,%4d)",round(app.rd_point(1)),round(app.rd_point(2)));

                hold(app.UIAxes, "on")
                plot(app.UIAxes, app.rd_point(1,:), app.rd_point(2,:), 'go');
                hold(app.UIAxes, "off")

            end

            if app.LineButton.Value

                line(app.UIAxes, [1 5672], [loc(2) loc(2)] ,'Color','red');
                app.LineButton.Value = false;

            end

        end

        % Button pushed function: SavescreenButton
        function SavescreenButtonPushed(app, event)
            pic = app.allpic;
            imwrite(pic, "camerapic\" + string(datetime(datetime,'Format','yy-MM-dd=hh-mm-ss')) + '.png')
        end

        % Value changed function: MarksCheckBox
        function MarksCheckBoxValueChanged(app, event)
            value = app.MarksCheckBox.Value;
            
            if value
                hold(app.UIAxes, "on")
                plot(app.UIAxes, app.draw_points(1,:), app.draw_points(2,:), 'ro');
                hold(app.UIAxes, "off")
            end
        end

        % Button pushed function: ProbeGridButton
        function ProbeGridButtonPushed(app, event)
            clf(app.UIAxes)

            app.lu_point
            app.rd_point

            grid_points_x = ((1:app.XSpinner.Value)-1).*(app.rd_point(1) - app.lu_point(1))./(app.XSpinner.Value-1) + app.lu_point(1) + app.offsetXSpinner.Value;
            grid_points_y = ((1:app.YSpinner.Value)-1).*(app.rd_point(2) - app.lu_point(2))./(app.YSpinner.Value-1) + app.lu_point(2) + app.offsetYSpinner.Value;

            % hold(app.UIAxes, "on")
            % plot(app.UIAxes, grid_points_x, grid_points_y, 'bo');
            % 
            % hold(app.UIAxes, "off")
            
            % % draw horizontal line
            % for i=1:(app.YSpinner.Value)
            %     line(app.UIAxes, [grid_points_x(1) grid_points_x(end)], [grid_points_y(i) grid_points_y(i)] ,'Color','blue');
            % end
            % 
            % % draw vertical line
            % for i=1:(app.XSpinner.Value)
            %     line(app.UIAxes, [grid_points_x(i) grid_points_x(i)], [grid_points_y(1) grid_points_y(end)] ,'Color','blue');
            % end

            % draw rectangle
            for xx = 1:(app.XSpinner.Value)
                for yy = 1:(app.YSpinner.Value)
                    rectangle(app.UIAxes, 'Position', [grid_points_x(xx) grid_points_y(yy) 48 24], 'EdgeColor', 'r');
                end
            end

            for xx = 1:(app.XSpinner.Value)
                text(app.UIAxes, grid_points_x(xx), grid_points_y(1)-10, sprintf("%d",xx), 'Color','red');
            end

            for yy = 1:(app.YSpinner.Value)
                text(app.UIAxes, grid_points_x(1)-10, grid_points_y(yy), sprintf("%d",yy), 'Color','red', 'HorizontalAlignment', 'right');
            end
            
            % x_line_start = [grid_points_x' ones(app.XSpinner.Value,1).*grid_points_y(1)];
            % x_line_end = [grid_points_x' ones(app.XSpinner.Value,1).*grid_points_y(end)];
            % 
            % line(app.UIAxes, x_line_start, x_line_end);
            
        end

        % Value changed function: TriggerEnableSwitch
        function TriggerEnableSwitchValueChanged(app, event)
            value = app.TriggerEnableSwitch.Value;
            src = getselectedsource(app.vid);
            
            if value == "On"
                src.TriggerMode = "On";
            else
                src.TriggerMode = "Off";
            end
        end

        % Button pushed function: LEDGridButton
        function LEDGridButtonPushed(app, event)
            
            lednum = 74;
            offset = 51.7567;
            app.lu_point
            grid_points_x = (0:lednum).*(offset)+app.lu_point(1);
            grid_points_y = (0:lednum).*(offset/2)+app.lu_point(2);
            for xx = 1:lednum
                text(app.UIAxes, grid_points_x(xx) + offset/2, app.lu_point(2), sprintf("%d",xx), 'Color','red','HorizontalAlignment','center');
            end
            for yy = 1:lednum
                text(app.UIAxes, app.lu_point(1), grid_points_y(yy)+(offset/4), sprintf("%d",yy), 'Color','red', 'HorizontalAlignment', 'right');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ALim = [0 1];
            colormap(app.UIAxes, 'jet')
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.GrabButton = uibutton(app.UIFigure, 'push');
            app.GrabButton.ButtonPushedFcn = createCallbackFcn(app, @GrabButtonPushed, true);
            app.GrabButton.Text = 'Grab';
            app.BackgroundCheckBox = uicheckbox(app.UIFigure);
            app.BackgroundCheckBox.Text = 'Background';
            app.BackgroundCheckBox.Value = true;
            app.SnapshotButton = uibutton(app.UIFigure, 'push');
            app.SnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @SnapshotButtonPushed, true);
            app.SnapshotButton.Text = 'Snapshot';
            app.ExposureEditFieldLabel = uilabel(app.UIFigure);
            app.ExposureEditFieldLabel.Text = 'Exposure';
            app.ExposureEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExposureEditField.ValueDisplayFormat = '%.0f';
            app.ExposureEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureEditFieldValueChanged, true);
            app.ExposureEditField.Value = 15000;
            app.PointsButton = uibutton(app.UIFigure, 'state');
            app.PointsButton.Text = 'Points';
            app.MarksCheckBox = uicheckbox(app.UIFigure);
            app.MarksCheckBox.ValueChangedFcn = createCallbackFcn(app, @MarksCheckBoxValueChanged, true);
            app.MarksCheckBox.Text = 'Marks';
            app.SavescreenButton = uibutton(app.UIFigure, 'push');
            app.SavescreenButton.ButtonPushedFcn = createCallbackFcn(app, @SavescreenButtonPushed, true);
            app.SavescreenButton.Text = 'Save screen';
            app.LUButton = uibutton(app.UIFigure, 'state');
            app.LUButton.Text = 'LU';
            app.RDButton = uibutton(app.UIFigure, 'state');
            app.RDButton.Text = 'RD';
            app.Label = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.XSpinnerLabel = uilabel(app.UIFigure);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.UIFigure);
            app.XSpinner.Value = 32;
            app.YSpinnerLabel = uilabel(app.UIFigure);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.UIFigure);
            app.YSpinner.Value = 32;
            app.ProbeGridButton = uibutton(app.UIFigure, 'push');
            app.ProbeGridButton.ButtonPushedFcn = createCallbackFcn(app, @ProbeGridButtonPushed, true);
            app.ProbeGridButton.Text = 'Probe Grid';
            app.LineButton = uibutton(app.UIFigure, 'state');
            app.LineButton.Text = 'Line';
            app.offsetXSpinnerLabel = uilabel(app.UIFigure);
            app.offsetXSpinnerLabel.Text = 'offsetX';
            app.offsetXSpinner = uispinner(app.UIFigure);
            app.offsetXSpinner.Value = -8;
            app.offsetYSpinnerLabel = uilabel(app.UIFigure);
            app.offsetYSpinnerLabel.Text = 'offsetY';
            app.offsetYSpinner = uispinner(app.UIFigure);
            app.offsetYSpinner.Value = -12;
            app.TriggerEnableSwitchLabel = uilabel(app.UIFigure);
            app.TriggerEnableSwitchLabel.Text = 'Trigger Enable';
            app.TriggerEnableSwitch = uiswitch(app.UIFigure, 'slider');
            app.TriggerEnableSwitch.ValueChangedFcn = createCallbackFcn(app, @TriggerEnableSwitchValueChanged, true);
            app.LEDGridButton = uibutton(app.UIFigure, 'push');
            app.LEDGridButton.ButtonPushedFcn = createCallbackFcn(app, @LEDGridButtonPushed, true);
            app.LEDGridButton.Text = 'LED Grid';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end