classdef matrixscan_trigger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TrigButton               matlab.ui.control.Button
        MaxvoltVSpinner_2        matlab.ui.control.Spinner
        MaxvoltVSpinner          matlab.ui.control.Spinner
        ClearButton              matlab.ui.control.StateButton
        SaveIVButton             matlab.ui.control.Button
        PointsSpinner            matlab.ui.control.Spinner
        PointsSpinnerLabel       matlab.ui.control.Label
        MeasureSwitch            matlab.ui.control.Switch
        MeasureSwitchLabel       matlab.ui.control.Label
        SamplespeedSpinner       matlab.ui.control.Spinner
        SamplespeedSpinnerLabel  matlab.ui.control.Label
        StopmASpinner            matlab.ui.control.Spinner
        StopmASpinnerLabel       matlab.ui.control.Label
        StartmASpinner           matlab.ui.control.Spinner
        StartmASpinnerLabel      matlab.ui.control.Label
        IVScanButton             matlab.ui.control.Button
        SavematrixButton         matlab.ui.control.Button
        CurrentmASpinner         matlab.ui.control.Spinner
        CurrentmASpinnerLabel    matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        OpenButton               matlab.ui.control.Button
        NIDropDown               matlab.ui.control.DropDown
        NIDropDownLabel          matlab.ui.control.Label
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        daq_device; % Description
        set_current;
        output_sig;
        output_sig_trig;
        resistance;
        total_sweep_result;
    end
    
    methods (Access = public)
        
        function plotAnalogData(app,~,~)
            data = read(app.daq_device, app.daq_device.ScansAvailableFcnCount);
            data = data.(1)';
            volt_result = [data(2:1024) data(1)];
            app.UITable.Data.matRes = volt_result' ./ (app.set_current ./ 1000);
            app.resistance = reshape(volt_result,[32 32]);
            imagesc(app.UIAxes, app.resistance);
            colorbar(app.UIAxes); clim(app.UIAxes,[app.MaxvoltVSpinner_2.Value app.MaxvoltVSpinner.Value]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            dq = daqlist;
            app.NIDropDown.Items = dq.DeviceID;
            
            % app.valtable = table();
            % app.valtable.matRow = zeros(1024,1);
            % app.valtable.matCol = zeros(1024,1);
            % app.valtable.matRes = zeros(1024,1);
            % app.valtable.matInd = boolean(zeros(1024,1));
            % k = 1;
            % 
            % for i=1:32
            %     for j=1:32
            %         app.valtable.matRow(k) = i;
            %         app.valtable.matCol(k) = j;
            %         app.valtable.matRes(k) = 0;
            %         app.valtable.matInd(k) = false;
            %         k = k+1;
            %     end
            % end
            % 
            % app.UITable.Data = app.valtable;

            app.UITable.Data = table();
            app.UITable.Data.matRow = zeros(1024,1);
            app.UITable.Data.matCol = zeros(1024,1);
            app.UITable.Data.matRes = zeros(1024,1);
            app.UITable.Data.matInd = logical(zeros(1024,1));
            k = 1;

            for i=1:32
                for j=1:32
                    app.UITable.Data.matRow(k) = i;
                    app.UITable.Data.matCol(k) = j;
                    app.UITable.Data.matRes(k) = 0;
                    app.UITable.Data.matInd(k) = false;
                    k = k+1;
                end
            end

        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            devname = app.NIDropDown.Value;

            app.daq_device = daq("ni");

            addoutput(app.daq_device, devname, "port0/line8:17", "Digital");

            addoutput(app.daq_device, devname, "ao2", "Voltage");

            addoutput(app.daq_device, devname, "port0/line28", "Digital");

            ch = addinput(app.daq_device, devname, "ai29", "Voltage");
            ch.Range = [0 5];
            ch.TerminalConfig = "SingleEnded";

            app.output_sig = load("output_sig.mat").output_sig;
            app.output_sig_trig = load("output_sig_trig.mat").output_sig_trig;
            
            app.OpenButton.Enable = false;
        end

        % Value changed function: MeasureSwitch
        function MeasureSwitchValueChanged(app, event)
            value = app.MeasureSwitch.Value;
            
            if value == "On"
                % set current
                app.set_current = app.CurrentmASpinner.Value;
                % for i=1:1024
                %     app.output_sig(i,11) = app.set_current ./ 19.94;
                % end
                app.output_sig(:,11) = ones(1024,1) .* app.set_current ./ 19.94;
                app.output_sig(1,12) = 1;
                % prepare dq
                flush(app.daq_device);
                app.daq_device.Rate = app.SamplespeedSpinner.Value;
                preload(app.daq_device, app.output_sig);
                app.daq_device.ScansAvailableFcn = @app.plotAnalogData;
                app.daq_device.ScansAvailableFcnCount = 1024;
                start(app.daq_device,"RepeatOutput");
                
            else
                stop(app.daq_device);
                flush(app.daq_device);
                app.output_sig(1,11) = 0;
                write(app.daq_device, app.output_sig(1,:));
            end
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;


            hold(app.UIAxes, "on");
            plot(app.UIAxes,app.UITable.Data.matRow(selection(:,1)),app.UITable.Data.matCol(selection(:,1)),"ro");
            hold(app.UIAxes, "off");
            
        end

        % Button pushed function: IVScanButton
        function IVScanButtonPushed(app, event)
            start_freq = app.StartmASpinner.Value;
            stop_freq = app.StopmASpinner.Value;
            sweep_points = app.PointsSpinner.Value;
            app.daq_device.Rate = app.SamplespeedSpinner.Value;
            
            % prepare sweep current list
            sweep_curr_list = [start_freq (1:(sweep_points-1)).*(stop_freq - start_freq)./(sweep_points-1) + start_freq];
            
            % prepare final output matrix
            out_sweep = [];
            sweep_list = app.UITable.Selection(:,1);
            sweep_result = [];
            for i = 1:sweep_points
                out_sweep = app.output_sig(sweep_list,:);
                out_sweep(:,11) = ones(length(sweep_list),1) .* sweep_curr_list(i) ./ 19.94;
                write(app.daq_device, out_sweep);
                res = read(app.daq_device).(1);
                sweep_result = [sweep_result res];
                
            end
            app.total_sweep_result(:,1) = sweep_curr_list(:);
            app.total_sweep_result = [app.total_sweep_result sweep_result'];

            hold(app.UIAxes2,"on");
            plot(app.UIAxes2, sweep_result, sweep_curr_list);
        end

        % Button pushed function: SavematrixButton
        function SavematrixButtonPushed(app, event)
            matrix_resistance = app.resistance;
            save("matrixresistance\" + string(datetime(datetime,'Format','yy-MM-dd=hh-mm-ss')),"matrix_resistance");
        end

        % Button pushed function: SaveIVButton
        function SaveIVButtonPushed(app, event)
            sweep_result = app.total_sweep_result;
            save("iv_result\" + string(datetime(datetime,'Format','yy-MM-dd=hh-mm-ss')),"sweep_result");
        end

        % Value changed function: ClearButton
        function ClearButtonValueChanged(app, event)
            value = app.ClearButton.Value;
            app.total_sweep_result = [];
            clf(app.UIAxes2);
        end

        % Button pushed function: TrigButton
        function TrigButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [1 32];
            app.UIAxes.YLim = [1 32];
            app.UIAxes.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32];
            app.UIAxes.YTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32];
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'Voltage (V)')
            ylabel(app.UIAxes2, 'Current (mA)')
            zlabel(app.UIAxes2, 'Z')
            app.NIDropDownLabel = uilabel(app.UIFigure);
            app.NIDropDownLabel.Text = 'NI';
            app.NIDropDown = uidropdown(app.UIFigure);
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Row'; 'Col'; 'Res'; 'Ind'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false false true true];
            app.UITable.ColumnEditable = [false false false true];
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.CurrentmASpinnerLabel = uilabel(app.UIFigure);
            app.CurrentmASpinnerLabel.Text = 'Current (mA)';
            app.CurrentmASpinner = uispinner(app.UIFigure);
            app.CurrentmASpinner.Value = 1;
            app.SavematrixButton = uibutton(app.UIFigure, 'push');
            app.SavematrixButton.ButtonPushedFcn = createCallbackFcn(app, @SavematrixButtonPushed, true);
            app.SavematrixButton.Text = 'Save matrix';
            app.IVScanButton = uibutton(app.UIFigure, 'push');
            app.IVScanButton.ButtonPushedFcn = createCallbackFcn(app, @IVScanButtonPushed, true);
            app.IVScanButton.Text = 'IV Scan';
            app.StartmASpinnerLabel = uilabel(app.UIFigure);
            app.StartmASpinnerLabel.Text = 'Start (mA)';
            app.StartmASpinner = uispinner(app.UIFigure);
            app.StartmASpinner.Limits = [0 20];
            app.StopmASpinnerLabel = uilabel(app.UIFigure);
            app.StopmASpinnerLabel.Text = 'Stop (mA)';
            app.StopmASpinner = uispinner(app.UIFigure);
            app.StopmASpinner.Limits = [0.1 20];
            app.StopmASpinner.Value = 5;
            app.SamplespeedSpinnerLabel = uilabel(app.UIFigure);
            app.SamplespeedSpinnerLabel.Text = 'Sample speed';
            app.SamplespeedSpinner = uispinner(app.UIFigure);
            app.SamplespeedSpinner.Limits = [1 2047];
            app.SamplespeedSpinner.Value = 2047;
            app.MeasureSwitchLabel = uilabel(app.UIFigure);
            app.MeasureSwitchLabel.Text = 'Measure';
            app.MeasureSwitch = uiswitch(app.UIFigure, 'slider');
            app.MeasureSwitch.ValueChangedFcn = createCallbackFcn(app, @MeasureSwitchValueChanged, true);
            app.PointsSpinnerLabel = uilabel(app.UIFigure);
            app.PointsSpinnerLabel.Text = 'Points';
            app.PointsSpinner = uispinner(app.UIFigure);
            app.PointsSpinner.Limits = [1 Inf];
            app.PointsSpinner.Value = 201;
            app.SaveIVButton = uibutton(app.UIFigure, 'push');
            app.SaveIVButton.ButtonPushedFcn = createCallbackFcn(app, @SaveIVButtonPushed, true);
            app.SaveIVButton.Text = 'Save IV';
            app.ClearButton = uibutton(app.UIFigure, 'state');
            app.ClearButton.ValueChangedFcn = createCallbackFcn(app, @ClearButtonValueChanged, true);
            app.ClearButton.Text = 'Clear';
            app.MaxvoltVSpinner = uispinner(app.UIFigure);
            app.MaxvoltVSpinner.Limits = [0 5];
            app.MaxvoltVSpinner.Value = 5;
            app.MaxvoltVSpinner_2 = uispinner(app.UIFigure);
            app.MaxvoltVSpinner_2.Limits = [0 5];
            app.MaxvoltVSpinner_2.Value = 1;
            app.TrigButton = uibutton(app.UIFigure, 'push');
            app.TrigButton.ButtonPushedFcn = createCallbackFcn(app, @TrigButtonPushed, true);
            app.TrigButton.Text = 'Trig';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matrixscan_trigger

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end