classdef zaxiscontrol < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ControlAppUIFigure       matlab.ui.Figure
        DownButton               matlab.ui.control.Button
        LiftupButton_2           matlab.ui.control.Button
        VacuumSwitch             matlab.ui.control.Switch
        VacuumSwitchLabel        matlab.ui.control.Label
        LaserDropDown            matlab.ui.control.DropDown
        LaserDropDownLabel       matlab.ui.control.Label
        mSpinner                 matlab.ui.control.Spinner
        mSpinnerLabel            matlab.ui.control.Label
        SavepositionButton       matlab.ui.control.Button
        CleardataButton          matlab.ui.control.Button
        DirectdownButton         matlab.ui.control.Button
        DirectupButton           matlab.ui.control.Button
        SavedataButton           matlab.ui.control.Button
        RelativepositionLabel    matlab.ui.control.Label
        mLabel_2                 matlab.ui.control.Label
        AbsolutepositionLabel    matlab.ui.control.Label
        mLabel_3                 matlab.ui.control.Label
        ZeroforceButton          matlab.ui.control.Button
        NLabel                   matlab.ui.control.Label
        ForceLabel               matlab.ui.control.Label
        MotorSwitch              matlab.ui.control.Switch
        MotorSwitchLabel         matlab.ui.control.Label
        SamplespeedSpinner       matlab.ui.control.Spinner
        SamplespeedSpinnerLabel  matlab.ui.control.Label
        NIDropDown               matlab.ui.control.DropDown
        NIDropDownLabel          matlab.ui.control.Label
        MeasureSwitch            matlab.ui.control.Switch
        MeasureSwitchLabel       matlab.ui.control.Label
        MovespeedKnob            matlab.ui.control.Knob
        MovespeedKnobLabel       matlab.ui.control.Label
        OpenButton               matlab.ui.control.Button
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        stage_force_record = [];
        now_force;
        now_absolute_position;
        force_zero_val = 14.9553;
        vacuum_record = [];
        absolute_distance_record = [];
        relative_distance_record = [];

        now_relative_position = 0; % Description
    end
    
    properties (Access = public)
        daq_device_analog; 
        daq_device_digital;
        daq_device_clock;
        daq_device_clockPin;
        laser_serialport;
    end
    
    methods (Access = public)
        
        function plotAnalogData(app,~,~)
            
            % Read data from analog channel
            data = read(app.daq_device_analog, app.daq_device_analog.ScansAvailableFcnCount);

            % Prepare each bar for sensor
            weight = [mean(data.(1)) mean(data.(2)) mean(data.(3)) mean(data.(4))];
            
            k = 498;
            b = -1289 + 2.5*k;

            bar(app.UIAxes, weight.*k+b); ylim(app.UIAxes, [-200 1000]);
            
            % Calculate total force
            force = (data.(1) + data.(2) + data.(3) + data.(4)).*k+b;
            force = force .* 0.0098;
            app.stage_force_record = [app.stage_force_record (force - app.force_zero_val)'];
            plot(app.UIAxes2, app.stage_force_record);
            
            % display force value
            app.now_force = mean(force);
            app.NLabel.Text = sprintf("%.2f N", app.now_force - app.force_zero_val);
            
            % display vacuum
            app.vacuum_record = [app.vacuum_record ((data.(5)-3)./2.*100)'];
            plot(app.UIAxes_2, app.vacuum_record);
            
            % read laser distance
            resp = writeread(app.laser_serialport,"%01#RMD**");
            distance = sscanf(resp,"%%01$RMD%d**") ./ 10;

            app.now_absolute_position = distance;
            
            app.absolute_distance_record = [app.absolute_distance_record distance];
            plot(app.UIAxes_3, app.absolute_distance_record);
            app.mLabel_3.Text = sprintf("%.0f μm", distance);

            app.relative_distance_record = [app.relative_distance_record app.now_relative_position];
            
        end

    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % search daq device
            a = load('z.mat');
            plot(app.UIAxes2,a.force);
            plot(app.UIAxes_3,a.distance);
            plot(app.UIAxes_2,a.vacuum);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            % laser
            app.laser_serialport = serialport(app.LaserDropDown.Value,19200,"Timeout",5);
            configureTerminator(app.laser_serialport,"CR");

            % register weight
            devname = app.NIDropDown.Value;

            app.daq_device_analog = daq("ni");

            ch1 = addinput(app.daq_device_analog, devname, "ai2", "Voltage");
            ch1.Range = [-2 2];
            ch1.TerminalConfig = "SingleEnded";
            
            ch2 = addinput(app.daq_device_analog, devname,"ai3","Voltage");
            ch2.Range = [-2 2];
            ch2.TerminalConfig = "SingleEnded";

            ch3 = addinput(app.daq_device_analog, devname,"ai4","Voltage");
            ch3.Range = [-2 2];
            ch3.TerminalConfig = "SingleEnded";

            ch4 = addinput(app.daq_device_analog, devname,"ai5","Voltage");
            ch4.Range = [-2 2];
            ch4.TerminalConfig = "SingleEnded";

            ch5 = addinput(app.daq_device_analog, devname,"ai0","Voltage");
            ch5.Range = [-5 5];
            ch5.TerminalConfig = "SingleEnded";

            dq = daq("ni");
            addoutput(dq, devname, "ao0", "Voltage");
            write(dq, 0);
            
            % register motor
            app.daq_device_clock = daq("ni");
            app.daq_device_clockPin = addoutput(app.daq_device_clock, devname, "ctr0", "PulseGeneration");
            clkTerminal = app.daq_device_clockPin.Terminal;
            
            app.daq_device_digital = daq("ni");
            addoutput(app.daq_device_digital, devname, "Port0/Line0:2","Digital");
            
            addclock(app.daq_device_digital,"ScanClock","External", devname + "/" + clkTerminal);
            
            app.OpenButton.Enable = "off";
            
        end

        % Value changed function: MeasureSwitch
        function MeasureSwitchValueChanged(app, event)

            value = app.MeasureSwitch.Value;
            if value == "On"
                app.daq_device_analog.Rate = app.SamplespeedSpinner.Value;
                app.daq_device_analog.ScansAvailableFcn = @app.plotAnalogData;
                app.daq_device_analog.ScansAvailableFcnCount = 20;
                start(app.daq_device_analog,"continuous");
            else
                stop(app.daq_device_analog)
            end
        end

        % Button pushed function: ZeroforceButton
        function ZeroforceButtonPushed(app, event)
            app.force_zero_val = app.now_force;
        end

        % Value changed function: MotorSwitch
        function MotorSwitchValueChanged(app, event)
            value = app.MotorSwitch.Value;
            
            clockFreq = app.MovespeedKnob.Value;

            if value == "On"
                app.daq_device_clockPin.Frequency = clockFreq;
                app.daq_device_digital.Rate = clockFreq;

                start(app.daq_device_clock,"continuous");
                write(app.daq_device_digital, [0 0 0]);
                while app.daq_device_digital.Running
                    
                end

                app.DirectupButton.Enable = "on";
                app.DirectdownButton.Enable = "on";

            else
                while app.daq_device_digital.Running
                    
                end
                write(app.daq_device_digital, [1 0 0]);
                while app.daq_device_digital.Running
                    
                end
                stop(app.daq_device_clock);

                app.DirectupButton.Enable = "off";
                app.DirectdownButton.Enable = "off";

            end

        end

        % Button pushed function: CleardataButton
        function CleardataButtonPushed(app, event)
            app.stage_force_record = [];
            app.vacuum_record = [];
            app.absolute_distance_record = [];
            app.relative_distance_record = [];
        end

        % Button pushed function: DirectupButton
        function DirectupButtonPushed(app, event)

            move_len = app.mSpinner.Value;
            app.now_relative_position = app.now_relative_position + move_len;
            app.mLabel_2.Text = sprintf("%.0f μm", app.now_relative_position);

            pulse_cnt = move_len * 5;
            
            move_mat = zeros(pulse_cnt * 2, 3);
            % 1 2 3 4
            % 1 3 5 7
            % 2 4 6 8
            for i=1:pulse_cnt
                move_mat((i-1)*2 + 1,:) = [0 0 1];
                move_mat(i*2,:) = [0 0 0];
            end
            write(app.daq_device_digital, move_mat);
        end

        % Button pushed function: DirectdownButton
        function DirectdownButtonPushed(app, event)

            move_len = app.mSpinner.Value;
            app.now_relative_position = app.now_relative_position - move_len;
            app.mLabel_2.Text = sprintf("%.0f μm", app.now_relative_position);

            pulse_cnt = move_len * 5
            
            move_mat = zeros(pulse_cnt * 2, 3);
            % 1 2 3 4
            % 1 3 5 7
            % 2 4 6 8
            for i=1:pulse_cnt
                move_mat((i-1)*2 + 1,:) = [0 1 1];
                move_mat(i*2,:) = [0 1 0];
            end
            write(app.daq_device_digital, move_mat);
        end

        % Button pushed function: SavepositionButton
        function SavepositionButtonPushed(app, event)
            app.ListBox.Items = [sprintf("%.1f", app.now_absolute_position) app.ListBox.Items];
            
        end

        % Value changed function: MovespeedKnob
        function MovespeedKnobValueChanged(app, event)
            value = app.MovespeedKnob.Value;
            app.daq_device_clockPin.Frequency = value;
            app.daq_device_digital.Rate = value;
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            force = app.stage_force_record;
            distance = app.absolute_distance_record;
            relative = app.relative_distance_record;
            vacuum = app.vacuum_record;
            save("machineforceandposition\" + string(datetime(datetime,'Format','yy-MM-dd=hh-mm-ss')),"force","distance","relative","vacuum");
        end

        % Value changed function: VacuumSwitch
        function VacuumSwitchValueChanged(app, event)
            value = app.VacuumSwitch.Value;

            devname = app.NIDropDown.Value;
            daq_device_vac = daq("ni");
            addoutput(daq_device_vac, devname, "Port0/Line5","Digital");
            if value == "On"

                write(daq_device_vac, 1);

            else
                write(daq_device_vac, 0);

            end
        end

        % Button pushed function: LiftupButton_2
        function LiftupButton_2Pushed(app, event)
            app.LiftupButton_2.Enable = "off";
            
            move_len = 30000;
            pulse_cnt = move_len * 5;
            move_mat = zeros(pulse_cnt * 2, 3);

            dir = 0;
            
            for i=1:pulse_cnt
                move_mat((i-1)*2 + 1,:) = [0 dir 1];
                move_mat(i*2,:) = [0 dir 0];
            end

            write(app.daq_device_digital, move_mat);

            
            app.DownButton.Enable = 'on';
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            app.DownButton.Enable = 'off';
            
            move_len = 30000;
            pulse_cnt = move_len * 5;
            move_mat = zeros(pulse_cnt * 2, 3);

            dir = 1;
            
            for i=1:pulse_cnt
                move_mat((i-1)*2 + 1,:) = [0 dir 1];
                move_mat(i*2,:) = [0 dir 0];
            end

            write(app.daq_device_digital, move_mat);

            app.LiftupButton_2.Enable = "on";
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ControlAppUIFigure = uifigure('Visible', 'off');
            app.ControlAppUIFigure.Name = 'Control App';
            app.UIAxes2 = uiaxes(app.ControlAppUIFigure);
            title(app.UIAxes2, 'Stage Force')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'N')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes_2 = uiaxes(app.ControlAppUIFigure);
            title(app.UIAxes_2, 'Vacuum')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'kPa')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.ControlAppUIFigure);
            title(app.UIAxes_3, 'Position')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'μm')
            zlabel(app.UIAxes_3, 'Z')
            app.OpenButton = uibutton(app.ControlAppUIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Enable = 'off';
            app.OpenButton.Text = 'Open';
            app.MovespeedKnobLabel = uilabel(app.ControlAppUIFigure);
            app.MovespeedKnobLabel.Text = 'Move speed';
            app.MovespeedKnob = uiknob(app.ControlAppUIFigure, 'continuous');
            app.MovespeedKnob.Limits = [80000 100000];
            app.MovespeedKnob.ValueChangedFcn = createCallbackFcn(app, @MovespeedKnobValueChanged, true);
            app.MovespeedKnob.Value = 100000;
            app.MeasureSwitchLabel = uilabel(app.ControlAppUIFigure);
            app.MeasureSwitchLabel.Text = 'Measure';
            app.MeasureSwitch = uiswitch(app.ControlAppUIFigure, 'slider');
            app.MeasureSwitch.ValueChangedFcn = createCallbackFcn(app, @MeasureSwitchValueChanged, true);
            app.MeasureSwitch.Value = 'On';
            app.NIDropDownLabel = uilabel(app.ControlAppUIFigure);
            app.NIDropDownLabel.Text = 'NI';
            app.NIDropDown = uidropdown(app.ControlAppUIFigure);
            app.NIDropDown.Items = {'Dev1'};
            app.NIDropDown.Value = 'Dev1';
            app.SamplespeedSpinnerLabel = uilabel(app.ControlAppUIFigure);
            app.SamplespeedSpinnerLabel.Text = 'Sample speed';
            app.SamplespeedSpinner = uispinner(app.ControlAppUIFigure);
            app.SamplespeedSpinner.Limits = [0 10000];
            app.SamplespeedSpinner.Value = 100;
            app.MotorSwitchLabel = uilabel(app.ControlAppUIFigure);
            app.MotorSwitchLabel.Text = 'Motor';
            app.MotorSwitch = uiswitch(app.ControlAppUIFigure, 'slider');
            app.MotorSwitch.ValueChangedFcn = createCallbackFcn(app, @MotorSwitchValueChanged, true);
            app.MotorSwitch.Value = 'On';
            app.ForceLabel = uilabel(app.ControlAppUIFigure);
            app.ForceLabel.Text = 'Force';
            app.NLabel = uilabel(app.ControlAppUIFigure);
            app.NLabel.Text = '4.02 N';
            app.ZeroforceButton = uibutton(app.ControlAppUIFigure, 'push');
            app.ZeroforceButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroforceButtonPushed, true);
            app.ZeroforceButton.Text = 'Zero force';
            app.mLabel_3 = uilabel(app.ControlAppUIFigure);
            app.mLabel_3.Text = '4254 μm';
            app.AbsolutepositionLabel = uilabel(app.ControlAppUIFigure);
            app.AbsolutepositionLabel.Text = 'Absolute position';
            app.mLabel_2 = uilabel(app.ControlAppUIFigure);
            app.mLabel_2.Text = '0 μm';
            app.RelativepositionLabel = uilabel(app.ControlAppUIFigure);
            app.RelativepositionLabel.Text = 'Relative position';
            app.SavedataButton = uibutton(app.ControlAppUIFigure, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.DirectupButton = uibutton(app.ControlAppUIFigure, 'push');
            app.DirectupButton.ButtonPushedFcn = createCallbackFcn(app, @DirectupButtonPushed, true);
            app.DirectupButton.Text = 'Direct up';
            app.DirectdownButton = uibutton(app.ControlAppUIFigure, 'push');
            app.DirectdownButton.ButtonPushedFcn = createCallbackFcn(app, @DirectdownButtonPushed, true);
            app.DirectdownButton.Text = 'Direct down';
            app.CleardataButton = uibutton(app.ControlAppUIFigure, 'push');
            app.CleardataButton.ButtonPushedFcn = createCallbackFcn(app, @CleardataButtonPushed, true);
            app.CleardataButton.Text = 'Clear data';
            app.SavepositionButton = uibutton(app.ControlAppUIFigure, 'push');
            app.SavepositionButton.ButtonPushedFcn = createCallbackFcn(app, @SavepositionButtonPushed, true);
            app.SavepositionButton.Text = 'Save position';
            app.mSpinnerLabel = uilabel(app.ControlAppUIFigure);
            app.mSpinnerLabel.Text = 'μm';
            app.mSpinner = uispinner(app.ControlAppUIFigure);
            app.mSpinner.Value = 100;
            app.LaserDropDownLabel = uilabel(app.ControlAppUIFigure);
            app.LaserDropDownLabel.Text = 'Laser';
            app.LaserDropDown = uidropdown(app.ControlAppUIFigure);
            app.LaserDropDown.Items = {'COM4'};
            app.LaserDropDown.Value = 'COM4';
            app.VacuumSwitchLabel = uilabel(app.ControlAppUIFigure);
            app.VacuumSwitchLabel.Text = 'Vacuum';
            app.VacuumSwitch = uiswitch(app.ControlAppUIFigure, 'slider');
            app.VacuumSwitch.ValueChangedFcn = createCallbackFcn(app, @VacuumSwitchValueChanged, true);
            app.VacuumSwitch.Value = 'On';
            app.LiftupButton_2 = uibutton(app.ControlAppUIFigure, 'push');
            app.LiftupButton_2.ButtonPushedFcn = createCallbackFcn(app, @LiftupButton_2Pushed, true);
            app.LiftupButton_2.Text = 'Lift up';
            app.DownButton = uibutton(app.ControlAppUIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Enable = 'off';
            app.DownButton.Text = 'Down';
            % Show the figure after all components are created
            app.ControlAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = zaxiscontrol

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ControlAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ControlAppUIFigure)
        end
    end
end